classdef prac8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        PuntosagenerarporclaseEditField  matlab.ui.control.NumericEditField
        PuntosagenerarporclaseLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        PuntosacolocarEditField      matlab.ui.control.NumericEditField
        PuntosacolocarLabel          matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        BanderaDropDown              matlab.ui.control.DropDown
        BanderaDropDownLabel         matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BanderaDropDown
        function BanderaDropDownValueChanged(app, event)
            value = app.BanderaDropDown.Value;
            if(value == "Alemania")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Alemania.jpg";
                app.Image.ImageSource = ruta;
            elseif(value == "Belgica")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/belgica.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Colombia")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/colombia.png";
                app.Image.ImageSource = ruta;
            elseif(value == "Holanda")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Holanda.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Italia")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Italia.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Japon")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/japon.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Irlanda")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Irlanda.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Luxemburgo")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/luxemburgo.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Qatar")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/qatar.png";
                app.Image.ImageSource = ruta;
            elseif(value == "Blanco")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/blano.jpg";
                app.Image.ImageSource = ruta;
            end            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
                        
            value = app.BanderaDropDown.Value;
            if(value == "Alemania")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Alemania.jpg";                
            elseif(value == "Belgica")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/belgica.jpeg";              
            elseif(value == "Colombia")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/colombia.png";                
            elseif(value == "Holanda")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Holanda.jpeg";                
            elseif(value == "Italia")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Italia.jpeg";                
            elseif(value == "Japon")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/japon.jpeg";                
            elseif(value == "Irlanda")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Irlanda.jpeg";          
            elseif(value == "Luxemburgo")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/luxemburgo.jpeg";
            elseif(value == "Qatar")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/qatar.png";
            elseif(value == "Blanco")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/blano.jpg";
            end
            imagen=imread(ruta);
            image(imagen);
            hold on
            try
                num_val=app.PuntosacolocarEditField.Value;
                %fprintf("puntos: %d",num_val);
                [x_coordinates,y_coordinates]=ginput(num_val);
                scatter(x_coordinates,y_coordinates,'filled','^','c');
                colores=cell(num_val,3);
            
                ind =1;
                while ind ~= num_val+1
                    x_conv=cast(x_coordinates(ind),"int32");
                    y_conv=cast(y_coordinates(ind),"int32");
                    %r
                    colores{ind,1} = imagen(y_conv,x_conv,1);
                    %g
                    colores{ind,2} = imagen(y_conv,x_conv,2);
                    %b
                    colores{ind,3} = imagen(y_conv,x_conv,3);
                    ind = ind+1;
                end
                no_clases = 1;
                colores_mat = cell2mat(colores)
                for i = 1:1:num_val-1
                    if colores_mat(i) ~= colores_mat(i+1)
                       no_clases = no_clases+1;
                    end
                end
                no_clases 
            catch exception
                    fprintf("Excepcion \n");
                    a = exception
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BanderaDropDownLabel = uilabel(app.GridLayout3);
            app.BanderaDropDownLabel.Text = 'Bandera';
            app.BanderaDropDown = uidropdown(app.GridLayout3);
            app.BanderaDropDown.Items = {'Alemania', 'Belgica', 'Colombia', 'Holanda', 'Irlanda', 'Italia', 'Japon', 'Luxemburgo', 'Qatar', 'Blanco'};
            app.BanderaDropDown.ValueChangedFcn = createCallbackFcn(app, @BanderaDropDownValueChanged, true);
            app.BanderaDropDown.Placeholder = 'seleccionar';
            app.BanderaDropDown.Value = 'Alemania';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.PuntosacolocarLabel = uilabel(app.GridLayout4);
            app.PuntosacolocarLabel.Text = {'Puntos '; 'a'; 'colocar'};
            app.PuntosacolocarEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PuntosacolocarEditField.Limits = [1 20];
            app.PuntosacolocarEditField.RoundFractionalValues = 'on';
            app.PuntosacolocarEditField.ValueDisplayFormat = '%.0f';
            app.PuntosacolocarEditField.Value = 1;
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Alemania.jpg');
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.PuntosagenerarporclaseLabel = uilabel(app.GridLayout6);
            app.PuntosagenerarporclaseLabel.Text = {'Puntos a '; 'generar por clase'};
            app.PuntosagenerarporclaseEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PuntosagenerarporclaseEditField.Limits = [4 1000];
            app.PuntosagenerarporclaseEditField.ValueDisplayFormat = '%.0f';
            app.PuntosagenerarporclaseEditField.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prac8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end